classdef FINALAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        resultsTab                     matlab.ui.container.Tab
        HeartRateTextAreaLabel         matlab.ui.control.Label
        HeartRate                      matlab.ui.control.TextArea
        SystolicpressureTextAreaLabel  matlab.ui.control.Label
        Pressure                       matlab.ui.control.TextArea
        ECGTab                         matlab.ui.container.Tab
        ECG                            matlab.ui.control.UIAxes
        PPGTab                         matlab.ui.container.Tab
        PPG                            matlab.ui.control.UIAxes
        PleaseenteryourheightLabel     matlab.ui.control.Label
        heightcmEditFieldLabel         matlab.ui.control.Label
        heightcmEditField              matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = start(app)
             s10sit = readtable('s10_sit.csv');
             rpeaks=s10sit{1:242843,3:3};
             ecg=s10sit{1:242843,2:2};
             pleth_6 = s10sit{1:242843, 9:9};
             filter_band = [0.75 5]; % for bandpass filter 
             fs = 500; % sampling frequency
             pleth_6_filtered = bandpass(pleth_6, filter_band, fs);
             height=app.heightcmEditField.Value;
             p1=700;
             p2=766000;
             p4=9;
             p3=-1;
             BDC=0.5;
             
             count =0;
             start= 10001;
             stop = 15000;
             
             while(1)
             tic;
             start=start+(count*5000);
             stop=stop+(count*5000)
  
             t=linspace(0,10,5000);
             plot(app.ECG,t,ecg(start:stop));
             plot(app.PPG,t,pleth_6_filtered(start:stop))
             t1=find(rpeaks(start:stop))
             n=numel(t1);
             HR=n*6;
             hrvalue {1}= sprintf('%d\n',HR);
             app.HeartRate.Value=hrvalue;
             
             d1=diff(pleth_6_filtered(start:stop),1);
             d2=diff(pleth_6_filtered(start:stop),2);
             for(i=2:4998)
             if ((d2(i)<0.01)&&(d2(i)>-0.01)&&(d1(i)>d1(i-1))&&(d1(i)>d1(i+1))&&(d1(i)>0.8)) break; end
             end
             pwv=(height*BDC)/((i-t1(1))*2);
             sp=p1*pwv*(2.718)^(p3*pwv)+p2*pwv^p4+(120-15037);
             spvalue {1}= sprintf('%d\n',sp);
             app.Pressure.Value=spvalue;
             count=count+1;
             pause(10-toc);
             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            start(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.resultsTab = uitab(app.TabGroup);
            app.resultsTab.Title = 'results';
            app.HeartRateTextAreaLabel = uilabel(app.resultsTab);
            app.HeartRateTextAreaLabel.Text = 'Heart Rate';
            app.HeartRate = uitextarea(app.resultsTab);
            app.HeartRate.Editable = 'off';
            app.SystolicpressureTextAreaLabel = uilabel(app.resultsTab);
            app.SystolicpressureTextAreaLabel.Text = 'Systolic pressure';
            app.Pressure = uitextarea(app.resultsTab);
            app.Pressure.Editable = 'off';
            app.ECGTab = uitab(app.TabGroup);
            app.ECGTab.Title = 'ECG';
            app.ECG = uiaxes(app.ECGTab);
            title(app.ECG, 'ECG')
            xlabel(app.ECG, 'Time(s)')
            ylabel(app.ECG, 'Value')
            app.PPGTab = uitab(app.TabGroup);
            app.PPGTab.Title = 'PPG';
            app.PPG = uiaxes(app.PPGTab);
            title(app.PPG, 'PPG')
            xlabel(app.PPG, 'Time(s)')
            ylabel(app.PPG, 'Value')
            app.PleaseenteryourheightLabel = uilabel(app.UIFigure);
            app.PleaseenteryourheightLabel.Text = {'Please enter your height'; ''};
            app.heightcmEditFieldLabel = uilabel(app.UIFigure);
            app.heightcmEditFieldLabel.Text = 'height(cm)';
            app.heightcmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINALAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end